classdef KyvGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        RegultorSwitchLabel  matlab.ui.control.Label
        RegulatorSwitch      matlab.ui.control.Switch
        KnobLabel            matlab.ui.control.Label
        Knob                 matlab.ui.control.Knob
        Knob2Label           matlab.ui.control.Label
        Knob2                matlab.ui.control.Knob
        PlayButton           matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        state = [pi/2, 0];
        Tsim = inf;
        t = 0;
        tPlot = 0;
        dt = 1e-2;
        dtPlot = 1/30;
        output=0;
        m = 1;
        L = 10;
        g = 10;
        B = 1;
        sz1 = 0.2;
        sz2 = 1;
        sz3 = 0.2;
        k1 = 1;
        k2 = 1;
        r = pi;
        stopSim=0;
    end
    
    methods (Access = private)
        
    end
    
    methods (Access = public)
        
        function [u] = linearization_law(app,v)
            u = app.m*app.L^2*(v + sin(app.state(1))*app.g/app.L + app.B/app.m/app.L^2*app.state(2));
        end
        
        function [v] = control_law(app)
            v = -app.k1*app.state(2) + app.k2*(app.r - app.state(1));
        end
        
        function [new_state, output] = pendulum_step(app, input)
            value=app.RegulatorSwitch.Value;
            if value == "Off"
                input=0;
            end
            new_state = zeros(2,1);
            new_state(1) = app.state(1) + app.dt*app.state(2);
            new_state(2) = app.state(2) + app.dt*(input/app.m/app.L^2 - app.g/app.L*sin(new_state(1)) - app.B/app.m/app.L^2*app.state(2));
            output = new_state(1)-pi/2;
        end
        
        function [] = plot_pendulum(app)
            weightxy = [ app.L*cos(app.output), app.L*sin(app.output)];
            hold(app.UIAxes,'off')
            plot(app.UIAxes, [0 weightxy(1)], [0 weightxy(2)], 'k');
            hold(app.UIAxes,'on')
            circle(app, 0,0, app.sz1);
            circle(app, weightxy(1), weightxy(2), app.sz2);
            circle(app, weightxy(1), weightxy(2), app.sz3);
            axis(app.UIAxes,[(-app.L-app.sz2) (app.L+app.sz2) (-app.L-app.sz2) (app.L+app.sz2)]);
            grid(app.UIAxes,'on')
        end
        function [] = circle(app,x,y,r)
            
            th = 0:pi/50:2*pi;
            xunit = r * cos(th) + x;
            yunit = r * sin(th) + y;
            plot(app.UIAxes, xunit, yunit, 'k');
            
        end
        
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.output=app.state(1);
            [app.state,app.output] = pendulum_step(app, linearization_law(app,control_law(app)));
            app.t = app.t + app.dt;
            app.tPlot = app.tPlot + app.dt;
            plot_pendulum(app);
            app.tPlot = 0;
            
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            value = app.PlayButton.Value;
            if value
                app.PlayButton.Text="Pause";
                app.stopSim = 0;
                
                while app.t < app.Tsim
                    [app.state,app.output] = pendulum_step(app, linearization_law(app,control_law(app)));
                    app.t = app.t + app.dt;
                    app.tPlot = app.tPlot + app.dt;
                    if (app.tPlot > app.dtPlot)
                        plot_pendulum(app);
                        app.tPlot = 0;
                        drawnow
                    end
                    if app.stopSim
                        break
                    end
                end
            else
                app.PlayButton.Text="Play";
                app.stopSim = 1;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'Kyvadlo'; ''})
            app.RegultorSwitchLabel = uilabel(app.UIFigure);
            app.RegultorSwitchLabel.Text = 'Regulátor';
            app.RegulatorSwitch = uiswitch(app.UIFigure, 'slider');
            app.KnobLabel = uilabel(app.UIFigure);
            app.KnobLabel.Text = 'Knob';
            app.Knob = uiknob(app.UIFigure, 'continuous');
            app.Knob2Label = uilabel(app.UIFigure);
            app.Knob2Label.Text = 'Knob2';
            app.Knob2 = uiknob(app.UIFigure, 'continuous');
            app.PlayButton = uibutton(app.UIFigure, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = 'Play';
        end
    end

    methods (Access = public)

        % Construct app
        function app = KyvGui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end